classdef cellLinker_Part3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Goback1Button               matlab.ui.control.Button
        greentextCheckBox           matlab.ui.control.CheckBox
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        SetandcontinueButton        matlab.ui.control.Button
        afterLabel                  matlab.ui.control.Label
        beforeLabel                 matlab.ui.control.Label
        ROIafterEditField           matlab.ui.control.EditField
        ROIafterEditFieldLabel      matlab.ui.control.Label
        ROIbeforeEditField          matlab.ui.control.EditField
        ROIbeforeEditFieldLabel     matlab.ui.control.Label
        anatomicallabelButtonGroup  matlab.ui.container.ButtonGroup
        unselectedButton            matlab.ui.control.RadioButton
        NeitherButton               matlab.ui.control.RadioButton
        BothButton                  matlab.ui.control.RadioButton
        mRuby2Button                matlab.ui.control.RadioButton
        eYFPButton                  matlab.ui.control.RadioButton
        mRuby2Label                 matlab.ui.control.Label
        eYFPLabel                   matlab.ui.control.Label
        GCaMPLabel                  matlab.ui.control.Label
        ROILabel                    matlab.ui.control.Label
        UIAxes_8                    matlab.ui.control.UIAxes
        UIAxes_7                    matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        expDir % base directory of analyzed experiment
        iscell1 % iscell: classifier for good ROIs (before dataset)
        stat1 % stat: statistics for each cell (to be used for ROI drawing)
        iscell2 % "" (after dataset)
        stat2
        curcell1
        curcell2
        goodcells1
        goodcells2
        
        roiinfo1
        roiinfo2
        
        centers1
        centers2
        
        textlabels1
        textlabels2
        textcolor
        
        donecells1
    end
    
    methods (Access = private)
        
        function zoom2cell(app)
            zoomrange = 25;
            includerange = 15;
            if app.donecells1==0
                curcell = app.curcell1;
                curcellnum = app.goodcells1(curcell);
                center = app.centers1(curcell,:);
                othercenters = app.centers2;
                othercells = app.goodcells2;
            else
                curcell = app.curcell2;
                curcellnum = app.goodcells2(curcell);
                center = app.centers2(curcell,:);
                othercenters = app.centers1;
                othercells = app.goodcells1;
            end
            x = center(2);
            y = center(1);
            
            %zoom with limits
            xlim(app.UIAxes,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_2,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_2,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_3,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_3,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_4,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_4,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_5,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_5,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_6,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_6,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_7,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_7,[y-zoomrange y+zoomrange])
            xlim(app.UIAxes_8,[x-zoomrange x+zoomrange])
            ylim(app.UIAxes_8,[y-zoomrange y+zoomrange])
            
            %only write the text for rois in the very center of the zoomed in image?   
            try
                for i = 1:length(app.textlabels1)
                    delete(app.textlabels1(i));
                end
            end
            try
                for i = 1:length(app.textlabels2)
                    delete(app.textlabels2(i));
                end
            end
            if app.greentextCheckBox.Value
                app.textcolor = [0 0.5 0];
            else
                app.textcolor = [1 1 1];
            end
            if app.donecells1==0
                app.textlabels1 = text(app.UIAxes,x,y,num2str(curcellnum),'Color',app.textcolor,'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
            else
                app.textlabels2 = text(app.UIAxes_6,x,y,num2str(curcellnum),'Color',app.textcolor,'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
            end
            
            yclose = abs(othercenters(:,1)-y)<includerange;
            xclose = abs(othercenters(:,2)-x)<includerange;
            closecells = find(xclose & yclose);
            for i = 1:length(closecells)
                x = othercenters(closecells(i),2);
                y = othercenters(closecells(i),1);
                othercellnum = othercells(closecells(i));
                if app.donecells1==0
                    app.textlabels2(i) = text(app.UIAxes_6,x,y,num2str(othercellnum),'Color',app.textcolor,'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
                else
                    app.textlabels1(i) = text(app.UIAxes,x,y,num2str(othercellnum),'Color',app.textcolor,'HorizontalAlignment',"center",'VerticalAlignment',"middle"); %white text over colored ROI?
                end
            end           
        end
        
        function setcolorbutton(app, num)
            switch num
                case 1
                    app.eYFPButton.Value = 1;
                case 2
                    app.mRuby2Button.Value = 1;
                case 3
                    app.BothButton.Value = 1;
                case 4
                    app.NeitherButton.Value = 1;
                case 5
                    app.unselectedButton.Value = 1;
                otherwise
                    app.unselectedButton.Value = 1;
            end
        end
        
        function color = getcolorbutton(app)
            if app.NeitherButton.Value
                color = 4;
            elseif app.unselectedButton.Value
                color = 5;
            elseif app.eYFPButton.Value
                color = 1;
            elseif app.mRuby2Button.Value
                color = 2;
            elseif app.BothButton.Value
                color = 3;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% ask user for experiment directory
            app.expDir = uigetdir("","Select an analyzed experiment directory");
%             app.expDir = "C:\Users\misaa\Desktop\2022-10-17 14-50 22_08_30_31 full20x_M_APP_ly6g_mouse_R_spot_2";
%             app.expDir = "C:\Users\misaa\Desktop\2022-10-18 10-52 22_05_08_09 fulltest_M_APP_ly6g";
            %just for testing purposes so I dont have to click every time
            
            %% load images
            %load suite2p data of before dataset
            filename = fullfile(app.expDir,"1","suite2p","suite2p","plane0","Fall.mat");
            if isfile(filename)
                %load files from "suite2p" folder
                suite2p_foldername = "suite2p";
            else
                filename = fullfile(app.expDir,"1","suite2p_comp","suite2p","plane0","Fall.mat");
                if isfile(filename)
                    suite2p_foldername = "suite2p_comp";
                else
                    error('cannot find suite2p Fall.mat file (in standard or comp folders)')
                end
            end
            suite2p = load(fullfile(app.expDir,"1",suite2p_foldername,"suite2p","plane0","Fall.mat"));
            app.iscell1 = suite2p.iscell(:,1); %iscell: classifier for good ROIs
            app.stat1 = suite2p.stat; %stat: statistics for each cell (to be used for ROI drawing)
            
            %load suite2p data of after dataset
            suite2p = load(fullfile(app.expDir,"2",suite2p_foldername,"suite2p","plane0","Fall.mat"));
            app.iscell2 = suite2p.iscell(:,1); %iscell: classifier for good ROIs
            app.stat2 = suite2p.stat; %stat: statistics for each cell (to be used for ROI drawing)
            
            %load gcamp images (avg and max ch2)
            avg1 = imread(fullfile(app.expDir,"1","MC functional","AVG_CH2_functional.tif"));
            max1 = imread(fullfile(app.expDir,"1","MC functional","MAX_CH2_functional.tif"));
            avg2 = imread(fullfile(app.expDir,"2","MC functional","AVG_CH2_functional.tif"));
            max2 = imread(fullfile(app.expDir,"2","MC functional","MAX_CH2_functional.tif"));
            
            %load eyfp/mruby2 images
            yfp1 = imread(fullfile(app.expDir,"1","eyfp_unmixed.tif"));
            mruby1 = imread(fullfile(app.expDir,"1","mruby_unmixed.tif"));
            yfp2 = imread(fullfile(app.expDir,"2","eyfp_unmixed.tif"));
            mruby2 = imread(fullfile(app.expDir,"2","mruby_unmixed.tif"));
            
            %% plot all images
            %plot ROIs
            roisimage = double(zeros(size(avg1)));
            numCells = sum(app.iscell1);
            colors = hsv(length(app.iscell1));
            stat = app.stat1;
            goodcells = find(app.iscell1);
            for c = 1:numCells
                cell = goodcells(c);
                roiimage = double(zeros(size(avg1)));
                x = stat{cell}.xpix + 1;
                y = stat{cell}.ypix + 1;
                l = stat{cell}.lam; %brightness
                if all(l==1)
                    %if manually drawn ROI (lam=1 for all pixels), increase
                    %brightness to that of an automatically drawn ROI
                    l(:) = max(stat{goodcells(end)}.lam);
                end
                roiimage(sub2ind(size(roiimage),y,x)) = l;
                roiimage = roiimage.*permute(colors(c,:),[1 3 2]);
                roisimage = roisimage+roiimage;
            end
            roisimage = roisimage/prctile(roisimage,99,"all");
            image(app.UIAxes,roisimage); %plot the image
            axis(app.UIAxes,'off')
            max_image_length = max(size(roisimage,1:2));
            xlim(app.UIAxes,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes,[0.5 max_image_length-0.5])
            app.centers1 = nan(numCells,2);
            for c = 1:numCells
                cell = goodcells(c);
                app.centers1(c,:) = stat{cell}.med;
            end
            
            roisimage = double(zeros(size(avg2)));
            numCells = sum(app.iscell2);
            colors = hsv(length(app.iscell2));
            stat = app.stat2;
            goodcells = find(app.iscell2);
            for c = 1:numCells
                cell = goodcells(c);
                roiimage = double(zeros(size(avg2)));
                x = stat{cell}.xpix + 1;
                y = stat{cell}.ypix + 1;
                l = stat{cell}.lam; %brightness
                if all(l==1)
                    l(:) = max(stat{goodcells(end)}.lam);
                end
                roiimage(sub2ind(size(roiimage),y,x)) = l;
                roiimage = roiimage.*permute(colors(c,:),[1 3 2]);
                roisimage = roisimage+roiimage;
            end
            roisimage = roisimage/prctile(roisimage,99,"all");
            image(app.UIAxes_6,roisimage); %plot the image
            axis(app.UIAxes_6,'off')
            max_image_length = max(size(roisimage,1:2));
            xlim(app.UIAxes_6,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes_6,[0.5 max_image_length-0.5])
            app.centers2 = nan(numCells,2);
            for c = 1:numCells
                cell = goodcells(c);
                app.centers2(c,:) = stat{cell}.med;
            end
            
            %plot gcamp
            %create multicolor image of avg/max (avg in green, max in blue)
            gcamp = double(avg1); %convert to floating point (interpreted by image() as 0-1 brightness)
            gcamp = gcamp/prctile(gcamp,99,"all"); %scale to 0-1ish
            gcamp = repmat(gcamp,[1 1 3]); %repeat for all 3 color channels
            gcamp(:,:,3) = double(max1);
            gcamp(:,:,3) = gcamp(:,:,3)/prctile(gcamp(:,:,3),99,"all");
            gcamp(:,:,1) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_5,gcamp); %plot the image
            axis(app.UIAxes_5,'off')
            max_image_length = max(size(gcamp,1:2));
            xlim(app.UIAxes_5,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes_5,[0.5 max_image_length-0.5])
            
            gcamp = double(avg2); %convert to floating point (interpreted by image() as 0-1 brightness)
            gcamp = gcamp/prctile(gcamp,99,"all"); %scale to 0-1ish
            gcamp = repmat(gcamp,[1 1 3]); %repeat for all 3 color channels
            gcamp(:,:,3) = double(max2);
            gcamp(:,:,3) = gcamp(:,:,3)/prctile(gcamp(:,:,3),99,"all");
            gcamp(:,:,1) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_4,gcamp); %plot the image
            axis(app.UIAxes_4,'off')
            max_image_length = max(size(gcamp,1:2));
            xlim(app.UIAxes_4,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes_4,[0.5 max_image_length-0.5])
            
            %plot eyfp
            yfp1 = double(yfp1); %convert to floating point (interpreted by image() as 0-1 brightness)
            yfp1 =yfp1/prctile(yfp1,99,"all"); %scale to 0-1ish
            yfp1 = repmat(yfp1,[1 1 3]); %repeat for all 3 color channels
            yfp1(:,:,3) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_3,yfp1); %plot the image
            axis(app.UIAxes_3,'off')
            max_image_length = max(size(yfp1,1:2));
            xlim(app.UIAxes_3,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes_3,[0.5 max_image_length-0.5])
            
            yfp2 = double(yfp2); %convert to floating point (interpreted by image() as 0-1 brightness)
            yfp2 = yfp2/prctile(yfp2,99,"all"); %scale to 0-1ish
            yfp2 = repmat(yfp2,[1 1 3]); %repeat for all 3 color channels
            yfp2(:,:,3) = 0; %erase blue channel (R+G=Y)
            image(app.UIAxes_2,yfp2); %plot the image
            axis(app.UIAxes_2,'off')
            max_image_length = max(size(yfp2,1:2));
            xlim(app.UIAxes_2,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes_2,[0.5 max_image_length-0.5])
            
            %plot mruby2
            mruby1 = double(mruby1); %convert to floating point (interpreted by image() as 0-1 brightness)
            mruby1 = mruby1/prctile(mruby1,99,"all"); %scale to 0-1ish
            mruby1 = repmat(mruby1,[1 1 3]); %repeat for all 3 color channels
            mruby1(:,:,[2 3]) = 0; %erase green and blue channels
            image(app.UIAxes_7,mruby1); %plot the image
            axis(app.UIAxes_7,'off')
            max_image_length = max(size(mruby1,1:2));
            xlim(app.UIAxes_7,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes_7,[0.5 max_image_length-0.5])
            
            mruby2 = double(mruby2); %convert to floating point (interpreted by image() as 0-1 brightness)
            mruby2 = mruby2/prctile(mruby2,99,"all"); %scale to 0-1ish
            mruby2 = repmat(mruby2,[1 1 3]); %repeat for all 3 color channels
            mruby2(:,:,[2 3]) = 0; %erase green and blue channels
            image(app.UIAxes_8,mruby2); %plot the image
            axis(app.UIAxes_8,'off')
            max_image_length = max(size(mruby2,1:2));
            xlim(app.UIAxes_8,[0.5 max_image_length-0.5]) %zoom in on image size
            ylim(app.UIAxes_8,[0.5 max_image_length-0.5])
            
            %% pick 1st cell
            app.goodcells1 = find(app.iscell1);
            app.goodcells2 = find(app.iscell2);
            app.roiinfo1 = nan(length(app.iscell1),3);
            app.roiinfo2 = nan(length(app.iscell2),3);
            app.curcell1 = 1;
            app.curcell2 = 0;
            app.donecells1=0;
            app.zoom2cell(); %get x,y coord of cell, zoom in to cell
            app.ROIbeforeEditField.Value = num2str(app.goodcells1(app.curcell1));
            app.ROIafterEditField.Value = num2str(nan);
            app.beforeLabel.FontSize = 16;
            app.beforeLabel.FontWeight = 'bold';
            
            %update labels
            app.Label.Text = [num2str(app.curcell1) '/' num2str(length(app.goodcells1))];
            app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
            app.ROIbeforeEditField.Editable = 'off';
            
        end

        % Button pushed function: SetandcontinueButton
        function SetandcontinueButtonPushed(app, event)
            app.anatomicallabelButtonGroup.BackgroundColor = [0.94 0.94 0.94];
            color = app.getcolorbutton();
%             if color==5
%                 app.anatomicallabelButtonGroup.BackgroundColor = [0.94 0.94 0.5];
%                 return;
%             end
            
            if app.donecells1==0
                curcellnum = app.goodcells1(app.curcell1);
                app.roiinfo1(curcellnum,1) = app.goodcells1(app.curcell1); %cell# of current dataset
                app.roiinfo1(curcellnum,2) = color;% color of cell (1=eyfp, 2=mruby, 3=both, 4=neither, 5=unselected)
                if isnan(str2double(app.ROIafterEditField.Value))
                    app.roiinfo1(curcellnum,3) = NaN;
                else
                    cell2 = str2double(app.ROIafterEditField.Value);
                    app.roiinfo1(curcellnum,3) = cell2; % cell# of other dataset
                    
                    %update roiinfo2 as well
                    app.roiinfo2(cell2,1) = cell2;
                    app.roiinfo2(cell2,2) = color;
                    app.roiinfo2(cell2,3) = curcellnum;
                end
                
                %select next roi
                app.curcell1 = app.curcell1+1;
                if app.curcell1<=length(app.goodcells1)
                    curcellnum = app.goodcells1(app.curcell1);
                    app.ROIbeforeEditField.Value = num2str(curcellnum);
                    app.setcolorbutton(app.roiinfo1(curcellnum,2));
                    app.ROIafterEditField.Value = num2str(app.roiinfo1(curcellnum,3));
                    app.Label.Text = [num2str(app.curcell1) '/' num2str(length(app.goodcells1))];
                else
                    app.curcell1 = length(app.goodcells1);
                    app.donecells1 = 1;
                    
                    %pick 1st after cell
                    app.Label.Text = [num2str(length(app.goodcells1)) '/' num2str(length(app.goodcells1))];
                    app.ROIbeforeEditField.Editable = 'on';
                    app.ROIafterEditField.Editable = 'off';
                    app.curcell2 = 1;
                    curcellnum = app.goodcells2(app.curcell2);
                    app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
                    app.ROIbeforeEditField.Value = num2str(app.roiinfo2(curcellnum,3));
                    app.setcolorbutton(app.roiinfo2(curcellnum,2))
                    app.ROIafterEditField.Value = num2str(curcellnum);
                    app.afterLabel.FontSize = 16;
                    app.afterLabel.FontWeight = 'bold';
                    app.beforeLabel.FontSize = 12;
                    app.beforeLabel.FontWeight = 'normal';
                end
                
            else
                % update roiinfo
                curcellnum = app.goodcells2(app.curcell2);
                app.roiinfo2(curcellnum,1) = curcellnum; %cell# of current dataset
                app.roiinfo2(curcellnum,2) = color;
                if isnan(str2double(app.ROIbeforeEditField.Value))
                    cell1 = NaN;
                    app.roiinfo2(curcellnum,3) = cell1;
                else
                    cell1 = str2double(app.ROIbeforeEditField.Value);
                    app.roiinfo2(curcellnum,3) = cell1; % cell# of other dataset
                    
                    %update roiinfo1 as well
                    app.roiinfo1(cell1,1) = cell1;
                    app.roiinfo1(cell1,2) = color;
                    app.roiinfo1(cell1,3) = curcellnum;
                end
                
                %select next roi
                app.curcell2 = app.curcell2+1;
                if app.curcell2<=length(app.goodcells2)
                    curcellnum = app.goodcells2(app.curcell2);
                    app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
                    app.ROIbeforeEditField.Value = num2str(app.roiinfo2(curcellnum,3));
                    app.setcolorbutton(app.roiinfo2(curcellnum,2))
                    app.ROIafterEditField.Value = num2str(curcellnum);
                else
                    roi_info_1 = app.roiinfo1;
                    roi_info_2 = app.roiinfo2;
                    save(fullfile(app.expDir,'roi_info.mat'),'roi_info_1','roi_info_2')
                    msgbox(['ROI info saved as "' fullfile(app.expDir,'roi_info.mat') '"']);
                    app.delete
                    return;
                end
            end
            if isvalid(app)
                zoom2cell(app)
            end
        end

        % Value changed function: greentextCheckBox
        function greentextCheckBoxValueChanged(app, event)
            zoom2cell(app)            
        end

        % Button pushed function: Goback1Button
        function Goback1ButtonPushed(app, event)
            if app.donecells1==0
                if app.curcell1==1
                    return;
                end
                app.curcell1 = app.curcell1-1;
                curcellnum = app.goodcells1(app.curcell1);
                app.Label.Text = [num2str(app.curcell1) '/' num2str(length(app.goodcells1))];
                app.setcolorbutton(app.roiinfo1(app.goodcells1(app.curcell1),2))
                app.ROIbeforeEditField.Value = num2str(app.roiinfo1(curcellnum,1));
                app.ROIafterEditField.Value = num2str(app.roiinfo1(curcellnum,3));
                app.Label.Text = [num2str(app.curcell1) '/' num2str(length(app.goodcells1))];
                
                
            else
                if app.curcell2==1
                    return;
                end
                app.curcell2 = app.curcell2-1;
                curcellnum = app.goodcells2(app.curcell2);
                app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
                app.setcolorbutton(app.roiinfo2(curcellnum,2))
                app.ROIbeforeEditField.Value = num2str(app.roiinfo2(curcellnum,1));
                app.ROIafterEditField.Value = num2str(app.roiinfo1(curcellnum,3));
                app.Label_2.Text = [num2str(app.curcell2) '/' num2str(length(app.goodcells2))];
            end
            
            zoom2cell(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.ROILabel = uilabel(app.UIFigure);
            app.ROILabel.Text = 'ROI';
            app.GCaMPLabel = uilabel(app.UIFigure);
            app.GCaMPLabel.Text = 'GCaMP';
            app.eYFPLabel = uilabel(app.UIFigure);
            app.eYFPLabel.Text = 'eYFP';
            app.mRuby2Label = uilabel(app.UIFigure);
            app.mRuby2Label.Text = 'mRuby2';
            app.anatomicallabelButtonGroup = uibuttongroup(app.UIFigure);
            app.anatomicallabelButtonGroup.Title = 'anatomical label';
            app.eYFPButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.eYFPButton.Text = 'eYFP';
            app.mRuby2Button = uiradiobutton(app.anatomicallabelButtonGroup);
            app.mRuby2Button.Text = 'mRuby2';
            app.BothButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.BothButton.Text = 'Both';
            app.NeitherButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.NeitherButton.Text = 'Neither';
            app.unselectedButton = uiradiobutton(app.anatomicallabelButtonGroup);
            app.unselectedButton.Text = 'unselected';
            app.unselectedButton.Value = true;
            app.ROIbeforeEditFieldLabel = uilabel(app.UIFigure);
            app.ROIbeforeEditFieldLabel.Text = 'ROI # (before)';
            app.ROIbeforeEditField = uieditfield(app.UIFigure, 'text');
            app.ROIbeforeEditField.Value = '0';
            app.ROIafterEditFieldLabel = uilabel(app.UIFigure);
            app.ROIafterEditFieldLabel.Text = 'ROI # (after)';
            app.ROIafterEditField = uieditfield(app.UIFigure, 'text');
            app.ROIafterEditField.Value = 'NaN';
            app.beforeLabel = uilabel(app.UIFigure);
            app.beforeLabel.Text = 'before';
            app.afterLabel = uilabel(app.UIFigure);
            app.afterLabel.Text = 'after';
            app.SetandcontinueButton = uibutton(app.UIFigure, 'push');
            app.SetandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @SetandcontinueButtonPushed, true);
            app.SetandcontinueButton.Text = 'Set and continue';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.greentextCheckBox = uicheckbox(app.UIFigure);
            app.greentextCheckBox.ValueChangedFcn = createCallbackFcn(app, @greentextCheckBoxValueChanged, true);
            app.greentextCheckBox.Text = 'green text';
            app.Goback1Button = uibutton(app.UIFigure, 'push');
            app.Goback1Button.ButtonPushedFcn = createCallbackFcn(app, @Goback1ButtonPushed, true);
            app.Goback1Button.Text = 'Go back 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellLinker_Part3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end