classdef manualRegistration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FinishedButton    matlab.ui.control.Button
        CWButton          matlab.ui.control.Button
        CCWButton         matlab.ui.control.Button
        RotationLabel     matlab.ui.control.Label
        TranslationLabel  matlab.ui.control.Label
        leftButton        matlab.ui.control.Button
        rightButton       matlab.ui.control.Button
        downButton        matlab.ui.control.Button
        upButton          matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        rotation % angle of rotation of registered image
        translation % translation [vertical, horizontal] of registered image
        editing % currently editing or finished
    end
    
    properties (Access = private)
        fixed % fixed position image
        moving % image to be registered (moved)
    end
    
    methods (Access = private)
        
        function updateRegistration(app)
            app.TranslationLabel.Text = ['Translation [' num2str(app.translation(1)) ',' num2str(app.translation(2)) ']'];
            app.RotationLabel.Text = ['Rotation [' num2str(app.rotation) ']'];
            
            tmp = app.fixed;
            tmp = tmp./prctile(tmp(:),98);

            tmpmoving = app.moving;
            tmpmoving = tmpmoving./prctile(tmpmoving(:),98);
            tform = rigidtform2d(app.rotation,app.translation);
            centerOutput = affineOutputView(size(tmpmoving),tform,"BoundsStyle","CenterOutput");
            tmpmoving = imwarp(tmpmoving,tform,"OutputView",centerOutput);

            tmp = repmat(tmp,[1 1 3]);
            tmp(:,:,[1 3]) = repmat(tmpmoving,[1 1 2]);
            
            imshow(tmp,'Parent',app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fixed, moving)
            app.fixed = fixed;
            app.moving = moving;
            app.translation = zeros(1,2);
            app.rotation = 0;
            app.editing = 'on';
            updateRegistration(app);
        end

        % Button pushed function: upButton
        function upButtonPushed(app, event)
            app.translation(2) = app.translation(2) - 1;
            updateRegistration(app);
        end

        % Button pushed function: downButton
        function downButtonPushed(app, event)
            app.translation(2) = app.translation(2) + 1;
            updateRegistration(app);
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            app.translation(1) = app.translation(1) + 1;
            updateRegistration(app);
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            app.translation(1) = app.translation(1) - 1;
            updateRegistration(app);
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            app.rotation = app.rotation + 1;
            updateRegistration(app);
        end

        % Button pushed function: CCWButton
        function CCWButtonPushed(app, event)
            app.rotation = app.rotation - 1;
            updateRegistration(app);
        end

        % Button pushed function: FinishedButton
        function FinishedButtonPushed(app, event)
            app.editing = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.upButton = uibutton(app.UIFigure, 'push');
            app.upButton.ButtonPushedFcn = createCallbackFcn(app, @upButtonPushed, true);
            app.upButton.Text = 'up';
            app.downButton = uibutton(app.UIFigure, 'push');
            app.downButton.ButtonPushedFcn = createCallbackFcn(app, @downButtonPushed, true);
            app.downButton.Text = 'down';
            app.rightButton = uibutton(app.UIFigure, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Text = 'right';
            app.leftButton = uibutton(app.UIFigure, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Text = 'left';
            app.TranslationLabel = uilabel(app.UIFigure);
            app.TranslationLabel.Text = 'Translation [0,0]';
            app.RotationLabel = uilabel(app.UIFigure);
            app.RotationLabel.Text = 'Rotation [0]';
            app.CCWButton = uibutton(app.UIFigure, 'push');
            app.CCWButton.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCWButton.Text = 'CCW';
            app.CWButton = uibutton(app.UIFigure, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Text = 'CW';
            app.FinishedButton = uibutton(app.UIFigure, 'push');
            app.FinishedButton.ButtonPushedFcn = createCallbackFcn(app, @FinishedButtonPushed, true);
            app.FinishedButton.Text = 'Finished';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualRegistration(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end